classdef drtaNWB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        drta_loadTab                   matlab.ui.container.Tab
        GridLayoutLoad                 matlab.ui.container.GridLayout
        OutputLocationLabel            matlab.ui.control.Label
        LocationForOutput              matlab.ui.control.TextArea
        ChooseOutputLocationButton     matlab.ui.control.Button
        InputFileName                  matlab.ui.control.TextArea
        ConvertrhdFileButton           matlab.ui.control.StateButton
        OutputFileName                 matlab.ui.control.EditField
        NWBfilenameLabel               matlab.ui.control.Label
        IntanfilenameLabel             matlab.ui.control.Label
        CreateFileforConversionButton  matlab.ui.control.StateButton
        CurrentFileNumber              matlab.ui.control.NumericEditField
        NextFile                       matlab.ui.control.Button
        LastFile                       matlab.ui.control.Button
        FIlenumberProcessLabel         matlab.ui.control.Label
        FileNumber                     matlab.ui.control.NumericEditField
        NumberOfFileLabel              matlab.ui.control.Label
        NWBconvert                     matlab.ui.container.ButtonGroup
        SingleFileButton               matlab.ui.control.RadioButton
        BatchofFilesButton             matlab.ui.control.RadioButton
        drtaProcessVersion             matlab.ui.container.ButtonGroup
        drta30Button                   matlab.ui.control.RadioButton
        drta20Button                   matlab.ui.control.RadioButton
        AppType                        matlab.ui.container.ButtonGroup
        ViewandprocessrhdfileButton    matlab.ui.control.RadioButton
        rhdtoNWBButton                 matlab.ui.control.RadioButton
        file_name                      matlab.ui.control.TextArea
        trial_amt                      matlab.ui.control.NumericEditField
        Trial_Label                    matlab.ui.control.Label
        dt_amt                         matlab.ui.control.NumericEditField
        dt_Label                       matlab.ui.control.Label
        protocolDropDown               matlab.ui.control.DropDown
        ProgressReadout                matlab.ui.control.TextArea
        Load_Button                    matlab.ui.control.StateButton
        copyright_Label                matlab.ui.control.Label
        SelectFile_Button              matlab.ui.control.StateButton
        Browse_TracesTab               matlab.ui.container.Tab
        GridLayoutTraces               matlab.ui.container.GridLayout
        Plot_Panel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        TrialOutcome                   matlab.ui.control.Label
        NWB_CheckBox                   matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.StateButton
        NWB_Label                      matlab.ui.control.Label
        type_DropDown                  matlab.ui.control.DropDown
        thr_Label                      matlab.ui.control.Label
        thr_amt                        matlab.ui.control.NumericEditField
        nxSD_amt                       matlab.ui.control.NumericEditField
        nxSD_Label                     matlab.ui.control.Label
        max_amt                        matlab.ui.control.NumericEditField
        max_Label                      matlab.ui.control.Label
        min_Label                      matlab.ui.control.Label
        min_amt                        matlab.ui.control.NumericEditField
        Diff_CheckBox                  matlab.ui.control.CheckBox
        MClust_Button                  matlab.ui.control.StateButton
        Licks_CheckBox                 matlab.ui.control.CheckBox
        Yrange_DropDown                matlab.ui.control.DropDown
        Choice_DropDown                matlab.ui.control.DropDown
        Tnum_Label                     matlab.ui.control.Label
        Interval_Label                 matlab.ui.control.Label
        Interval_amt                   matlab.ui.control.NumericEditField
        Tnum_amt                       matlab.ui.control.NumericEditField
        Tnum_Rarrow                    matlab.ui.control.Button
        Tnum_Larrow                    matlab.ui.control.Button
        Yrange_amt                     matlab.ui.control.NumericEditField
        Yrange_Label                   matlab.ui.control.Label
        uV_Label                       matlab.ui.control.Label
        FileSettingsTab                matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        FSinput11                      matlab.ui.control.EditField
        ChooseLocationButton           matlab.ui.control.StateButton
        OutputlocationLabel            matlab.ui.control.Label
        ChooseFileButton               matlab.ui.control.StateButton
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        FSinput10no                    matlab.ui.control.RadioButton
        FSinput10yes                   matlab.ui.control.RadioButton
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        FSinput9no                     matlab.ui.control.RadioButton
        FSinput9yes                    matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        FSinput8no                     matlab.ui.control.RadioButton
        FSinput8yes                    matlab.ui.control.RadioButton
        ButtonGroup                    matlab.ui.container.ButtonGroup
        FSinput4no                     matlab.ui.control.RadioButton
        FSinput4yes                    matlab.ui.control.RadioButton
        FSinput7                       matlab.ui.control.EditField
        FSinput6                       matlab.ui.control.EditField
        FSinput5                       matlab.ui.control.EditField
        FSinput3                       matlab.ui.control.EditField
        FSinput2                       matlab.ui.control.EditField
        FSinput1                       matlab.ui.control.EditField
        FSinput0                       matlab.ui.control.EditField
        IncludesubjectmetadataLabel    matlab.ui.control.Label
        IncludetimesettingsLabel       matlab.ui.control.Label
        MergefilesLabel                matlab.ui.control.Label
        HighpassdescriptionLabel       matlab.ui.control.Label
        LowpassdescriptionLabel        matlab.ui.control.Label
        CompressionLevelLabel          matlab.ui.control.Label
        UseCompressionLabel            matlab.ui.control.Label
        Blocksperchunk1000Label        matlab.ui.control.Label
        SessiondescriptionNote1Note2Label  matlab.ui.control.Label
        NWBfilenameLabel_2             matlab.ui.control.Label
        IntanfilenameLabel_2           matlab.ui.control.Label
        SessionStartTimeSettingsTab    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        TSinput5                       matlab.ui.control.EditField
        TSinput4                       matlab.ui.control.EditField
        TSinput3                       matlab.ui.control.EditField
        TSinput2                       matlab.ui.control.EditField
        TSinput1                       matlab.ui.control.EditField
        TSinput0                       matlab.ui.control.EditField
        Manualsessionstartsecond43Label  matlab.ui.control.Label
        Manualsessionstartminute23Label  matlab.ui.control.Label
        Manualsessionstarthour16Label  matlab.ui.control.Label
        manualsessionstartday17Label   matlab.ui.control.Label
        Manualsessionstartmonth1Label  matlab.ui.control.Label
        Manualsessionstartyear2019Label  matlab.ui.control.Label
        SubjectMetadataSettingsTab     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        MSinput11                      matlab.ui.control.EditField
        MSinput10                      matlab.ui.control.EditField
        MSinput9                       matlab.ui.control.EditField
        ButtonGroup_5                  matlab.ui.container.ButtonGroup
        MSinput8no                     matlab.ui.control.RadioButton
        MSinput8yes                    matlab.ui.control.RadioButton
        MSinput7                       matlab.ui.control.EditField
        MSinput6                       matlab.ui.control.EditField
        MSinput5                       matlab.ui.control.EditField
        MSinput4                       matlab.ui.control.EditField
        MSinput3                       matlab.ui.control.EditField
        MSinput2                       matlab.ui.control.EditField
        MSinput1                       matlab.ui.control.EditField
        MSinput0                       matlab.ui.control.EditField
        SubjectDOBday21Label           matlab.ui.control.Label
        SubjectDOBmonth9Label          matlab.ui.control.Label
        SubjectDOByear2009Label        matlab.ui.control.Label
        IncludesubjectDOBLabel         matlab.ui.control.Label
        SubjectstrainLabel             matlab.ui.control.Label
        SubjectweightkgLabel           matlab.ui.control.Label
        SubjectIDLabel                 matlab.ui.control.Label
        SubjectspeciesLabel            matlab.ui.control.Label
        SubjectsexLabel                matlab.ui.control.Label
        SubjectgenotypeLabel           matlab.ui.control.Label
        SubjectdescriptionLabel        matlab.ui.control.Label
        SubjectageLabel                matlab.ui.control.Label
        SelectChannelsTab              matlab.ui.container.Tab
        ChannelPanel                   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
    end


    properties (Access = private)
        drta_Main % Links the main application
        drta_handles = struct(); % Description
        drta_Plot % Description
        OutputText % This is the text that is displayed on the GUI
        FlagAlpha %  Flag is used to indicate the first call to readout
        %         outputTextDisplay % Houses the text for the files that are created
        NWBFileName     % File name that will be used for the NWB file
        OutputLocation % Location of output files
        BatchFiles = struct();% Structure containing all the batch file inforation
        rhd = struct(); % Structure containing current .rhd file
        h % pointer for browse traces
        Flags = struct; % Description
    end

    methods (Access = public)

        function open_rhd_Callback(app)

            FileName=app.drta_handles.p.FileName;
            app.drta_handles.drtaWhichFile = FileName;

            %     %Setup the matlab header file
            [app.drta_handles.draq_p,app.drta_handles.draq_d]=drta_read_Intan_RHD2000_header(app.drta_handles.p.fullName,app.drta_handles.p.which_protocol,app.drta_handles);
            % end
            app.drta_handles.GUIs.drtaLoad = app;

            app.drta_handles.draq_p.dgordra=3;

            %{
            initial settings for which channels are shown 
            %}
            %finding the number of channels
            channel_total = app.drta_handles.draq_d.num_amplifier_channels;
            %determining how many rows are needed based on only 10 per row
            if channel_total > 10
                RowsNeeded = ceil(channel_total/10);
            else
                RowsNeeded = 1;
            end
            %creating the rows' heights of 25 points
            RowVals = num2cell(ones([1,RowsNeeded])*25);
            %assigning those values
            app.GridLayout4.RowHeight = RowVals;
            ColumnLimit = 10;   %initial conditions
            CurrentRow = 1; %initial conditions
            CurrentColumn = 1;  %initial conditions
            %creating each label and check box
            for fn = 1:channel_total
                %checking to see if the next row is needed
                if fn > ColumnLimit
                    CurrentRow = CurrentRow + 1;
                    CurrentColumn = 1;
                    ColumnLimit = ColumnLimit + 10;
                end
                %adding a 0 to the number if < 10 and creating a label
                if fn < 10
                    ChTitle = uilabel(app.GridLayout4,'Text',append('Channel 0',num2str(fn)));
                else
                    ChTitle = uilabel(app.GridLayout4,'Text',append('Channel ',num2str(fn)));
                end
                %label settings
                ChTitle.HorizontalAlignment = 'center';
                ChTitle.FontSize = 14;
                ChTitle.Layout.Row = CurrentRow;
                ChTitle.Layout.Column = CurrentColumn;
                %creating a checkbox
                ChCheckBox = uicheckbox(app.GridLayout4);
                %checkbox settings
                ChCheckBox.Layout.Row = CurrentRow;
                ChCheckBox.Layout.Column = CurrentColumn + 1;
                ChCheckBox.Text = {''};
                ChCheckBox.Value = 1;
                if fn < 10
                    ChCheckBox.Tag = append('CHbox_0',num2str(fn));
                else
                    ChCheckBox.Tag = append('CHbox_',num2str(fn));
                end
                %moves the index to the next label location
                CurrentColumn = CurrentColumn + 2;
            end
            %setting initial 16 channels to true
            if channel_total >= 16
                InitialMaxCh = 16;
            else
                InitialMaxCh = channel_total;
            end
            %             for sm = 1:InitialMaxCh
            %                 disp("need checkbox num")
            %                 ChildNum = find(app.GridLayout4.Children.Tag = 'CH1')
            %             end


            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;

            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=1;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;
            % app.drta_handles.p.which_protocol=1;


            if exist('drta_p','var') == 1 && isfield(drta_p,'doSubtract')
                app.drta_handles.p.doSubtract=drta_p.doSubtract;
                app.drta_handles.p.subtractCh=drta_p.subtractCh;
            else
                app.drta_handles.p.doSubtract=0;
                for i = 1:16
                    app.drta_handles.p.subtractCh{i,1}{:} = "18";
                end
            end

            for ii=1:app.drta_handles.draq_p.no_spike_ch
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end

            end

            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);

            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;

                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
                    %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
                    %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end
            end
            %             drtaNWB_figureControl(app,app.drta_handles);
            %             drtaNWB_PlotBrowseTraces(app.drta_handles);
        end

        function drtaOpenDG_Callback(app)
            %             drtaOpenDG_Callback(hObject, eventdata, app.drta_handles)
            % hObject    handle to drtaOpenDG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.drta_handles    structure with app.drta_handles and user data (see GUIDATA)


            FileName=app.drta_handles.p.FileName;
            set(app.drta_handles.drtaWhichFile,'String',FileName);
            load([app.drta_handles.p.fullName(1:end-2),'mat']);
            try
                app.drta_handles.draq_p=params;
                app.drta_handles.draq_d=data;
            catch
                app.drta_handles.draq_p=draq_p;
                app.drta_handles.draq_d=draq_d;
            end


            app.drta_handles.draq_p.dgordra=2;
            % sz_t_trial=size(draq_d.t_trial);
            % sz_samp=size(draq_d.samplesPerTrial);
            % if (sz_t_trial(2)<data.noTrials)||(sz_samp(2)<data.noTrials)
            %    app.drta_handles.draq_d.noTrials=min([sz_t_trial(2) sz_samp(2)]);
            % end
            %app.drta_handles.p.fid=fopen(app.drta_handles.p.fullName,'r');

            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;

            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=8;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;


            if exist('drta_p')==1
                if isfield(drta_p,'doSubtract')
                    app.drta_handles.p.doSubtract=drta_p.doSubtract;
                    app.drta_handles.p.subtractCh=drta_p.subtractCh;
                else
                    app.drta_handles.p.doSubtract=0;
                    app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
                end
            else
                app.drta_handles.p.doSubtract=0;
                app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
            end

            for (ii=1:app.drta_handles.draq_p.no_spike_ch)
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end

            end

            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);

            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;

                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
                    %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
                    %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end

            end

            app.drta_handles.p.trialNo=1;

            %Read recordings
            if app.drta_handles.p.read_entire_file==1
                app.drta_handles.draq_d.data=drtaReadData(app.drta_handles);
            end

            %Find the threshold for the licks
            app.drta_handles.p.lick_th_frac=0.3;
            one_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ninetynine_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ii_from=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time)...
                *app.drta_handles.draq_p.ActualRate+1);
            ii_to=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time...
                +app.drta_handles.p.display_interval)*app.drta_handles.draq_p.ActualRate)-2000;
            ii=19; %These are licks

            old_trial=app.drta_handles.p.trialNo;
            for trialNo=1:length(app.drta_handles.draq_d.t_trial)-1
                app.drta_handles.p.trialNo=trialNo;

                data_this_trial=drtaGetTraceData(app.drta_handles);

                this_data=[];
                this_data=data_this_trial(:,ii);
                one_per(trialNo)=prctile(this_data(ii_from:ii_to),0.5);
                ninetynine_per(trialNo)=prctile(this_data(ii_from:ii_to),99.5);
            end
            app.drta_handles.p.trialNo=old_trial;
            app.drta_handles.p.exc_sn_thr=app.drta_handles.p.lick_th_frac*mean(ninetynine_per-one_per);

            app.drta_handles.p.lfp.maxLFP=4100;
            app.drta_handles.p.lfp.minLFP=10;

            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);


            %Open threshold window
            h=drtaThresholdSnips;
            app.drta_handles.w.drtaThresholdSnips=h;
            drtaUpdateAllapp.drta_handlespw(hObject,app.drta_handles);
            drtaThresholdSnips('updateapp.drta_handles',h,eventdata,app.drta_handles);

            %Open browse traces
            h=drtaBrowseTraces;
            app.drta_handles.w.drtaBrowseTraces=h;
            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);
            % Update app.drta_handles structure here and elsewhere
            drtaUpdateAllapp.drta_handlespw(hObject, app.drta_handles);
            drtaBrowseTraces('updateapp.drta_handles',h,eventdata,app.drta_handles);
            drtaBrowseTraces('updateBrowseTraces',h);


        end


        %         function drtaBrowseDraPush_Callback(app, handles)
        %
        %             h=drtaBrowseTraces;
        %             handles.w.drtaBrowseTraces=h;
        %             % Update handles structure
        %             guidata(hObject, handles);
        %             % Update handles structure here and elsewhere
        %             drtaUpdateAllHandlespw(app, handles);
        %             drtaBrowseTraces('updateHandles',h,eventdata,handles);
        %             drtaBrowseTraces('updateBrowseTraces',h);
        %         end

        %         function drtaThresholdPush_Callback(app, handles)
        %
        %             h=drtaThresholdSnips;
        %             handles.w.drtaThresholdSnips=h;
        %             %handles.p.trialNo=1;
        %             % Update handles structure here and elsewhere
        %             drtaUpdateAllHandlespw(app, handles);
        %             drtaThresholdSnips('updateHandles',h,eventdata,handles);
        %         end

        %         function trialNo = getTrialNo(~, handles)
        %
        %             trialNo=handles.p.trialNo;
        %         end
        %
        %         function actualTrialNo = setTrialNo(app, trialNo)
        %             % hObject    handle to drtaBrowseDraPush (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    structure with handles and user data (see GUIDATA)
        %
        %             handles=guidata(hObject);
        %
        %             sztrials=size(handles.draq_d.t_trial);
        %             actualTrialNo=trialNo;
        %             if (trialNo<1)
        %                 actualTrialNo=1;
        %                 handles.p.trialNo=1;
        %             end
        %
        %             if (trialNo>sztrials(2))
        %                 actualTrialNo=sztrials(2);
        %                 handles.p.trialNo=sztrials(2);
        %             end
        %
        %             if (trialNo>=1)&&(trialNo<=sztrials(2))
        %                 actualTrialNo=trialNo;
        %                 handles.p.trialNo=trialNo;
        %             end
        %
        %             % Update handles structure
        %             guidata(hObject, handles);
        %         end


        %         function drtaUpdateAllHandlespw(app,newhandles)
        %             % hObject    handle to drtaBrowseDraPush (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    structure with handles and user data (see GUIDATA)
        %
        %             handles=app.drta_handles;
        %             handles.p=newhandles.p;
        %             handles.draq_p=newhandles.draq_p;
        %             % Update handles structure
        %             app.drta_handles = handles;
        %
        %             if handles.w.drtaBrowseTraces~=0
        %                 hObject=handles.w.drtaBrowseTraces;
        % %                 handles=guidata(hObject);
        %                 handles.p=newhandles.p;
        %                 handles.w=newhandles.w;
        %                 handles.draq_p=newhandles.draq_p;
        %                 % Update the handles structure
        % %                 guidata(hObject, handles);
        %                 %drtaBrowseTraces('updateBrowseTraces',hObject);
        %             end
        %
        % %             handles=guidata(gcbo);
        %             if handles.w.drtaThresholdSnips~=0
        %                 hObject=handles.w.drtaThresholdSnips;
        % %                 handles=guidata(hObject);
        %                 handles.p=newhandles.p;
        %                 handles.w=newhandles.w;
        %                 handles.draq_p=newhandles.draq_p;
        %                 % Update the handles structure
        % %                 guidata(hObject, handles);
        % %                 drtaThresholdSnips('drtaThSnSetTrials',hObject,handles);
        %                 %drtaThresholdSnips('drtaThresholdAndPlot',hObject,handles);
        %             end

        %             app.drta_handles = handles;
        %         end
        function VarUpdate(app,Vname,Vupdate)
            %{
            This funciton serves to replace the following
            setUpdatePreview
            drtaRadioFilter_Callback
            drtaBrTr3xSD_Callback
            drtaPSWhichChan_Callback
            drtaRadioExclSn_Callback
            drtaSub1_Callback
            drtaSub5_Callback
            drtaSub9_Callback
            drtaSub13_Callback
            drtaDiff_Callback 
            drtaSub2_Callback
            drtaSub3_Callback
            drtaSub4_Callback
            drtaSub6_Callback
            drtaSub7_Callback
            drtaSub8_Callback
            drtaSub10_Callback
            drtaSub11_Callback
            drtaSub12_Callback
            drtaSub14_Callback
            drtaSub15_Callback
            drtaSub16_Callback
            drtaLFPmin_Callback
            drtaLFPmax_Callback
            setThr_Callback
            updateBrowseTraces
            %}
            app.drta_handles.p.(Vname) = Vupdate;
            app.drta_handles = drtaNWB_figureControl(app,app.drta_handles);
            %             app.drta_handles = drtaNWB_PlotBrowseTraces(app.drta_handles);

        end


        function setTrialsOutcome(app, trialsOutcome)
            app.TrialOutcome.Text = trialsOutcome;
        end


        function ReadoutUpdate(app,textUpdate)
            TextPrint = textUpdate;
            OTsize = size(app.OutputText);
            if app.FlagAlpha == true
                app.OutputText{1,1} = append(app.OutputText{1,1},textUpdate);
                app.FlagAlpha = false;
            else
                app.OutputText{(OTsize(1)+1),1} = TextPrint;
            end
            app.ProgressReadout.Value = app.OutputText;
            pause(.1)
        end

        function trialNo = getTrialNo(app)
            trialNo=app.drta_handles.p.trialNo;
        end

        function actualTrialNo = setTrialNo(app,trialNo)
            sztrials=size(app.drta_handles.draq_d.t_trial);
            actualTrialNo=trialNo;
            if (trialNo<1)
                actualTrialNo=1;
                app.drta_handles.p.trialNo=1;
            end

            if (trialNo>sztrials(2))
                actualTrialNo=sztrials(2);
                app.drta_handles.p.trialNo=sztrials(2);
            end

            if (trialNo>=1)&&(trialNo<=sztrials(2))
                actualTrialNo=trialNo;
                app.drta_handles.p.trialNo=trialNo;
            end

        end


        function FigTitle_TrialsOutcome(app,mouse_results)
            app.TrialOutcome.Text = mouse_results;
        end
    end

    methods (Access = private)


        function figChange(app)
            %{
            This function is used to adjsut the figured displayed based on
            the user selections from the dropdown menus
            %}
            clf(app.drta_handles.s_handle,"reset")
            clear app.drta_handles.s_handle
            PSel = [32,34;30,32;28,30;26,28; ...
                24,26;22,24;20,22;18,20; ...
                16,18;14,16;12,14;10,12; ...
                8,10;6,8;4,6;2,4];
            %             Wct = 1;

            for ii = 1:16

                if app.drta_handles.p.subtractCH{1,ii}{:} == "Tet"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 17;
                elseif app.drta_handles.p.subtractCH{1,ii}{:} == "Avg"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 18;
                elseif app.drta_handles.p.subtractCH{1,ii}{:} == "No"
                    app.drta_handles.p.subtractCh{1,ii}{:} = 19;
                else

                end
                if ii < 16
                    SameAsNext = app.drta_handles.p.subtractCh{1,ii}{:} == app.drta_handles.p.subtractCh{1,ii+1}{:};
                elseif ii == 16
                    SameAsNext = app.drta_handles.p.subtractCh{1,ii}{:} == app.drta_handles.p.subtractCh{1,ii-1}{:};
                end
                if SameAsNext == ture
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                else
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                    app.drta_handles.plot.s_handle(ii) = uiaxes(drta_ax);
                    app.drta_handles.plot.s_handle(ii).Layout.Row = [stPlc PSel(ii,2)];
                    app.drta_handles.plot.s_handle(ii).Layout.Column = [2 9];
                    app.drta_handles.plot.s_handle(ii).XTickLabels = {};
                    app.drta_handles.plot.s_handle(ii).YTickLabels = {};
                    app.drta_handles.plot.s_handle(ii).Title.Visible = "off";
                    app.drta_handles.plot.s_handle(ii).Box = 'on';
                    currentInner = app.drta_handles.plot.s_handle(ii).InnerPosition;
                    if ii == 1
                        % lowermost plot is taller due to x-tic labels
                        app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+10, ...
                            currentInner(3), 40];
                    else
                        app.drta_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+3, ...
                            currentInner(3), 38];
                    end
                end
                stPlc = [];


            end
            drtaGenerateEvents(app.drta_handles)
        end


        function ClearAllConvertFileds(app)
            app.FSinput0.Value = "Select a new file";
            app.FSinput1.Value = "Choose a new file";
            app.FSinput2.Value = [];
            app.FSinput3.Value = "1000";
            app.FSinput5.Value = "4";
            app.FSinput6.Value = [];
            app.FSinput7.Value = [];
            app.TSinput0.Value = [];
            app.TSinput1.Value = [];
            app.TSinput2.Value = [];
            app.TSinput3.Value = [];
            app.TSinput4.Value = [];
            app.TSinput5.Value = [];
            app.MSinput0.Value = [];
            app.MSinput1.Value = [];
            app.MSinput2.Value = [];
            app.MSinput3.Value = [];
            app.MSinput4.Value = [];
            app.MSinput5.Value = [];
            app.MSinput6.Value = [];
            app.MSinput7.Value = [];
        end

        function LoadAllConvertFileds(app)

            filename = 'NWB_Settings.xlsx';
            app.FSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B3');
            FSimputTemp1 = readmatrix(filename,'Sheet',1,'Range','B4');
            app.FSinput1.Value = str(FSimputTemp1);
            app.FSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B5');
            app.FSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B6');
            app.FSinput4 = readmatrix(filename,'Sheet',1,'Range','B7');
            app.FSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B8');
            app.FSinput6.Value = readmatrix(filename,'Sheet',1,'Range','B9');
            app.FSinput7.Value = readmatrix(filename,'Sheet',1,'Range','B10');
            FSinputtemp8 = readmatrix(filename,'Sheet',1,'Range','B11');
            if FSinputtemp8 == "TRUE"
                app.FSinput4yes.Value = "true";
            elseif FSinputtemp8 == "FALSE"
                app.FSinput4no.Value = "true";
            end
            % Session Start Time settings

            FSinputtemp9 = readmatrix(filename,'Sheet',1,'Range','B13');
            if FSinputtemp9 == "TRUE"
                app.FSinput9yes.Value = "true";
            elseif FSinputtemp9 == "FALSE"
                app.FSinput9no.Value = "true";
            end
            app.TSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B14');
            app.TSinput1.Value = readmatrix(filename,'Sheet',1,'Range','B15');
            app.TSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B16');
            app.TSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B17');
            app.TSinput4.Value = readmatrix(filename,'Sheet',1,'Range','B18');
            app.TSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B19');
            % Subject Metadata Settings
            TSinputtemp10 = readmatrix(filename,'Sheet',1,'Range','B21');
            if TSinputtemp10 == "TRUE"
                app.FSinput10yes.Value = "true";
            elseif TSinputtemp10 == "FALSE"
                app.FSinput10no.Value = "true";
            end
            app.MSinput0.Value = readmatrix(filename,'Sheet',1,'Range','B22');
            app.MSinput1.Value = readmatrix(filename,'Sheet',1,'Range','B23');
            app.MSinput2.Value = readmatrix(filename,'Sheet',1,'Range','B24');
            app.MSinput3.Value = readmatrix(filename,'Sheet',1,'Range','B25');
            app.MSinput4.Value = readmatrix(filename,'Sheet',1,'Range','B26');
            app.MSinput5.Value = readmatrix(filename,'Sheet',1,'Range','B27');
            app.MSinput6.Value = readmatrix(filename,'Sheet',1,'Range','B28');
            app.MSinput7.Value = readmatrix(filename,'Sheet',1,'Range','B29');
            MSinputtemp8 = readmatrix(filename,'Sheet',1,'Range','B30');
            if MSinputtemp8 == "TRUE"
                app.MSinput8yes.Value = "true";
            elseif MSinputtemp8 == "False"
                app.MSinput8no.Value = "true";
            end
            app.MSinput9.Value = readmatrix(filename,'Sheet',1,'Range','B31');
            app.MSinput10.Value = readmatrix(filename,'Sheet',1,'Range','B32');
            app.MSinput11.Value = readmatrix(filename,'Sheet',1,'Range','B33');

        end

        function WritingAllConvertFields(app)
            filename = 'NWB_Settings.xlsx';
            writematrix(app.FSinput0.Value,filename,'Sheet',1,'Range','B3')
            writematrix(char(app.FSinput1.Value),filename,'Sheet',1,'Range','B4')
            writematrix(app.FSinput2.Value,filename,'Sheet',1,'Range','B5')
            writematrix(app.FSinput3.Value,filename,'Sheet',1,'Range','B6')
            if app.FSinput4yes.Value == 1
                FSinput4 = 'TRUE';
            else
                FSinput4 = 'FALSE';
            end
            writematrix(FSinput4,filename,'Sheet',1,'Range','B7')

            writematrix(app.FSinput5.Value,filename,'Sheet',1,'Range','B8')
            writematrix(app.FSinput6.Value,filename,'Sheet',1,'Range','B9')
            writematrix(app.FSinput7.Value,filename,'Sheet',1,'Range','B10')
            if app.FSinput8yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B11')
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B11')
            end
            %             app.BatchFiles.location = app.FSinput11.Value;
            % Session Start Time settings
            if app.FSinput9yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B13')
                writematrix(app.TSinput0.Value,filename,'Sheet',1,'Range','B14')
                writematrix(app.TSinput1.Value,filename,'Sheet',1,'Range','B15')
                writematrix(app.TSinput2.Value,filename,'Sheet',1,'Range','B16')
                writematrix(app.TSinput3.Value,filename,'Sheet',1,'Range','B17')
                writematrix(app.TSinput4.Value,filename,'Sheet',1,'Range','B18')
                writematrix(app.TSinput5.Value,filename,'Sheet',1,'Range','B19')
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B13')
            end
            % Subject Metadata Settings
            if app.FSinput10yes.Value == 1
                writematrix('TRUE',filename,'Sheet',1,'Range','B21')
                writematrix(app.MSinput0.Value,filename,'Sheet',1,'Range','B22')
                writematrix(app.MSinput1.Value,filename,'Sheet',1,'Range','B23')
                writematrix(app.MSinput2.Value,filename,'Sheet',1,'Range','B24')
                writematrix(app.MSinput3.Value,filename,'Sheet',1,'Range','B25')
                writematrix(app.MSinput4.Value,filename,'Sheet',1,'Range','B26')
                writematrix(app.MSinput5.Value,filename,'Sheet',1,'Range','B27')
                writematrix(app.MSinput6.Value,filename,'Sheet',1,'Range','B28')
                writematrix(app.MSinput7.Value,filename,'Sheet',1,'Range','B29')
                if app.MSinput8yes.Value == 1
                    writematrix('TRUE',filename,'Sheet',1,'Range','B30')
                    writematrix(app.MSinput9.Value,filename,'Sheet',1,'Range','B31')
                    writematrix(app.MSinput10.Value,filename,'Sheet',1,'Range','B32')
                    writematrix(app.MSinput11.Value,filename,'Sheet',1,'Range','B33')

                else
                    writematrix('FALSE',filename,'Sheet',1,'Range','B30')
                end
            else
                writematrix('FALSE',filename,'Sheet',1,'Range','B21')
            end
        end


        function CreatePlotWindow(app)
            %calling each checkbox to see if it is checked
            for nk = 2:2:size(app.GridLayout4.Children,1)
                ChannelValues(nk) = app.GridLayout4.Children(nk).Value;

            end
            %setting inital channel values
            app.drta_handles.p.VisableChannel = ChannelValues(2:2:end);

            %{
            Loads the locations for each plot
            %}
            pause(0.2);
            plotGrid = app.GridLayout5;
            app.GridLayout5.Children.delete
            RowsNeeded = sum(ChannelValues);
            Row_Spacing = {'1x',26,'1x'};
            for sw = 1:RowsNeeded-1
                Row_Spacing = [Row_Spacing,{26,'1x'}];
            end
            Row_space = floor((plotGrid.Position(4)-(RowsNeeded*5))/RowsNeeded);
            plotGrid.ColumnWidth = {120,'1x'};
%             Row_Height = num2cell(ones([1,RowsNeeded+1])*Row_space);
            Row_Height = Row_Spacing;
            Row_Height{end+1} = 50;
            plotGrid.RowHeight = Row_Height;
            plotGrid.RowSpacing = 5;
            RowsTaken = 0;
            for aj = 1:size(app.drta_handles.p.VisableChannel,2)
                if app.drta_handles.p.VisableChannel(aj) == 1
                    if aj < 10
                        ChTitle = uilabel(plotGrid,'Text',append('Channel 00',num2str(aj)));
                    elseif aj < 100
                        ChTitle = uilabel(plotGrid,'Text',append('Channel 0',num2str(aj)));
                    else
                        ChTitle = uilabel(plotGrid,'Text',append('Channel ',num2str(aj)));
                    end
                    ChTitle.HorizontalAlignment = 'center';
                    ChTitle.FontSize = 14;
                    ChTitle.Layout.Row = ((RowsNeeded-RowsTaken+1)*2);
                    RowsTaken = RowsTaken + 1;
                    ChTitle.Layout.Column = 1;
                end
            end
            %location within parint .....
            s_handle= uiaxes(app.GridLayout5);
            s_handle.Layout.Row = [1 ((RowsNeeded+1)*2)];
            s_handle.Layout.Column = 2;
            s_handle.XTickLabels = {};
            s_handle.YTickLabels = {};
            s_handle.Title.Visible = "off";
            s_handle.Box = 'on';
            s_handle.Tag = 'plotField';

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            This block starts a python env that allows any library to be
            used. The batch file strucutre is started along with
            determining which mode is being requested.
            %}
            pyenv("ExecutionMode","OutOfProcess")
            app.BatchFiles.table = table('size',[50,1],'VariableTypes',"cell");
            app.BatchFiles.amt = 0;
            app.BatchFiles.totCount = 0;
            AppTypeSelectionChanged(app);
            drtaProcessVersionSelectionChanged(app);
            %{
            Flags for setting different conditions
            %}
            app.Flags.ChannelSetup = true;
            app.Flags.fileLoaded = false;
            %{
            Setting inital valses for calling this app in other functions
            %}
            app.drta_handles.output = app;
            app.drta_handles.w.drta = app;
            app.drta_handles.time_before_FV = 1;
            app.drta_handles.w.drtaBrowseTraces=0;
            app.drta_handles.w.drtaThresholdSnips=0;
            %
            app.GridLayout.Visible = 'off';
            app.GridLayout2.Visible = 'off';
            app.GridLayout3.Visible = 'on';

            %{
            Setting up initial parameters/conditions for drta_handles.p
            %}
            app.drta_handles.p = drtaSetPreferences();
            app.drta_handles.p.trialNo = 1;
            app.drta_handles.p.low_filter = 1000;
            app.drta_handles.p.high_filter = 5000;
            app.drta_handles.p.whichPlot = 1;
            app.drta_handles.p.lastTrace = -1;
            app.drta_handles.p.exclude_secs = 2;
            app.drta_handles.p.set2p5SD = 0;
            app.drta_handles.p.setm2p5SD = 0;
            app.drta_handles.p.setnxSD = 0;
            app.drta_handles.p.nxSD = 2.5;
            app.drta_handles.p.showLicks = 1;
            app.drta_handles.p.exc_sn = 0;
            app.drta_handles.p.read_entire_file = 0;
            app.drta_handles.p.setThr = 0;
            app.drta_handles.p.thrToSet = 0;
            if ~isfield(app.drta_handles.p,'doSubtract')
                app.drta_handles.p.doSubtract = 0;
                app.drta_handles.p.subtractCh = cell(1,16);
            end
            app.drta_handles.p.threexsdexists(1:16) = 0;

            %             app.drta_handles=guidata(hObject);
            %             app.drta_handles.p=handdrta.p;
            %             app.drta_handles.w=handdrta.w;
            %             app.drta_handles.draq_p=handdrta.draq_p;
            %             app.drta_handles.draq_d=handdrta.draq_d;
            %             %app.drta_handles.p.which_channel=1;
            %             %app.drta_handles.p.which_display=1;
            %             %app.drta_handles.p.do_filter=1;
            %             app.drta_handles.w.trialOutcome=handles.trialOutcome;
            %             % app.drta_handles.p.doSubtract=0;
            %             % app.drta_handles.p.low_filter=1000;
            %             % app.drta_handles.p.high_filter=5000;
            %             % app.drta_handles.p.subtractCh=[1 8 11 15];

            %Please note that in the old files there is no handles.p.doSubtract


            %             if isfield(app.drta_handles.p,'exc_sn')
            %                 set(app.drta_handles.drtaRadioExclSn,'Value',app.drta_handles.p.exc_sn);
            %             else
            %                 app.drta_handles.p.exc_sn=0;
            %             end
            %
            app.drta_handles.draq_p.auto_thr_sign=1;
            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=1;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;


        end

        % Selection changed function: AppType
        function AppTypeSelectionChanged(app, event)
            selectedButton = app.AppType.SelectedObject;
            if selectedButton.Text == "rhd to NWB"
                app.drtaProcessVersion.Visible = "off";
                app.SelectFile_Button.Visible = "off";
                app.file_name.Visible = "off";
                app.protocolDropDown.Visible = "off";
                app.dt_Label.Visible = "off";
                app.dt_amt.Visible = "off";
                app.Trial_Label.Visible = "off";
                app.trial_amt.Visible = "off";
                app.Load_Button.Visible = "off";
                app.OutputLocationLabel.Visible = "on";
                app.NWBconvert.Visible = "on";
                app.ConvertrhdFileButton.Visible = "on";
                app.ConvertrhdFileButton.Enable = "off";
                app.OutputFileName.Visible = "on";
                app.NWBfilenameLabel.Visible = "on";
                app.IntanfilenameLabel.Visible = "on";
                app.InputFileName.Visible = "on";
                app.ChooseOutputLocationButton.Visible = "off";
                app.LocationForOutput.Visible = "on";
                NWBconvertSelectionChanged(app)
                app.GridLayoutTraces.Visible = "off";
                app.GridLayout.Visible = "on";
                app.GridLayout2.Visible = "on";
                app.GridLayout3.Visible = "on";
            elseif selectedButton.Text == "View and process rhd file"
                app.NWBconvert.Visible = "off";
                app.ConvertrhdFileButton.Visible = "off";
                app.OutputFileName.Visible = "off";
                app.NWBfilenameLabel.Visible = "off";
                app.IntanfilenameLabel.Visible = "off";
                app.InputFileName.Visible = "off";
                app.CreateFileforConversionButton.Visible = "off";
                app.NextFile.Visible = "off";
                app.LastFile.Visible = "off";
                app.CurrentFileNumber.Visible = "off";
                app.FIlenumberProcessLabel.Visible = "off";
                app.NumberOfFileLabel.Visible = "off";
                app.FileNumber.Visible = "off";
                app.ChooseOutputLocationButton.Visible = "off";
                app.LocationForOutput.Visible = "off";
                app.drtaProcessVersion.Visible = "on";
                app.SelectFile_Button.Visible = "on";
                app.file_name.Visible = "on";
                app.protocolDropDown.Visible = "on";
                app.dt_Label.Visible = "on";
                app.dt_amt.Visible = "on";
                app.Trial_Label.Visible = "on";
                app.trial_amt.Visible = "on";
                app.Load_Button.Visible = "on";
                app.GridLayoutTraces.Visible = "on";
                app.GridLayout.Visible = "off";
                app.GridLayout2.Visible = "off";
                app.GridLayout3.Visible = "on";
                app.OutputLocationLabel.Visible = "off";
            end
        end

        % Selection changed function: drtaProcessVersion
        function drtaProcessVersionSelectionChanged(app, event)
            app.GridLayoutTraces.Visible = "on";
            app.GridLayout3.Visible = "on";

        end

        % Value changed function: Yrange_amt
        function Yrange_amtValueChanged(app, event)
            %{
            This function works as intended based on the orignal guide GUI
            %}
            y_range = app.Yrange_amt.Value;
            %             v_range=y_range* app.drta_handles.draq_p.pre_gain* app.drta_handles.draq_p.daq_gain/1000000;


            if (app.drta_handles.p.which_channel==1)
                %Change for all channels
                for ii=1:app.drta_handles.draq_p.no_spike_ch
                    %         if v_range>handles.draq_p.inp_max
                    %             handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                    %         else
                    app.drta_handles.plot.s_handles(ii)=y_range;
                    app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                    %         end4
                end
            else
                ii=app.drta_handles.p.which_channel-1;
                %     if v_range>handles.draq_p.inp_max
                %         handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max(2)/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
                %     else
                app.drta_handles.plot.s_handles(ii)=y_range;
                app.drta_handles.draq_p.prev_ylim(ii)=y_range;
                %     end
            end
            %             drta(app.drta_handles.w.drta,'drtaUpdateAllHandlespw',app.drta_handles)
            drtaNWB_figureControl(app,app.drta_handles);
            %             drtaNWB_PlotBrowseTraces(app.drta_handles);
        end

        % Value changed function: Yrange_DropDown
        function Yrange_DropDownValueChanged(app, event)
            app.drta_handles.p.which_channel = app.Yrange_DropDown.Value;

        end

        % Value changed function: Interval_amt
        function Interval_amtValueChanged(app, event)
            time_interval = app.Interval_amt.Value;

            if(app.drta_handles.p.start_display_time+time_interval)>app.drta_handles.draq_p.sec_per_trigger
                time_interval= app.drta_handles.draq_p.sec_per_trigger-app.drta_handles.p.start_display_time;
            end

            app.drta_handles.p.display_interval=time_interval;
            app.Interval_amt.Value = app.drta_handles.p.display_interval;
            %             set(hObject,'String',num2str(handles.p.display_interval));

            %             % Update all handles.p structures
            %             drta('drtaUpdateAllHandlespw',app.drta_handles.w.drta,app.drta_handles);
            %             drtaPlotBrowseTraces(app.drta_handles);
        end

        % Callback function: Tnum_Larrow, Tnum_Rarrow, Tnum_amt
        function TrialNum_ButtonPushed(app, event)
            %{
            at some point both arrow can be combined using their tag names
            and combining the callback functions.
            %}
            switch event.Source.Tag
                case "TnumMinus"
                    newTrial= app.drta_handles.p.trialNo-1;
                case "TnumPlus"
                    newTrial=app.drta_handles.p.trialNo+1;
                case "TnumField"
                    newTrial = app.Tnum_amt.Value;
            end
            app.drta_handles.p.trialNo = setTrialNo(app, newTrial);
            app.Tnum_amt.Value = app.drta_handles.p.trialNo;
            VarUpdate(app,"trialNo",app.drta_handles.p.trialNo)
        end

        % Value changed function: MClust_Button
        function MClust_ButtonValueChanged(app, event)
            drtaGenerateMClust(app.drta_handles);
        end

        % Value changed function: Licks_CheckBox
        function Licks_CheckBoxValueChanged(app, event)
            app.drta_handles.p.exc_sn = app.Licks_CheckBox.Value;
            VarUpdate(app,"exc_sn",app.drta_handles.p.exc_sn)
        end

        % Value changed function: min_amt
        function ValueAmt_Changed(app, event)
            switch event.Source.Tag
                case "LFPmin"
                    app.drta_handles.p.lfp.minLFP = app.min_amt.Value;
                    VarUpdate(app,"minLFP",app.drta_handles.p.lfp.minLFP)
                case "LFPmax"
                    app.drta_handles.p.lfp.maxLFP = app.max_amt.Value;
                    VarUpdate(app,"maxLFP",app.drta_handles.p.lfp.maxLFP)
                case "Thr"
                    app.drta_handles.p.thrToSet = app.thr_amt.Value;
                    VarUpdate(app,"thrToSet",app.drta_handles.p.thrToSet)
                    if app.drta_handles.p.setThr ~= 1
                        app.drta_handles.p.setThr=1;
                        VarUpdate(app,"setThr",app.drta_handles.p.setThr)
                    end
                case "nxSD"
                    app.drta_handles.p.nxSD = app.nxSD_amt.Value;
                    app.drta_handles.p.setnxSD=1;
                    app.drta_handles = drtaNWB_figureControl(app, app.drta_handles);
                    %                     drtaNWB_PlotBrowseTraces(app.drta_handles);
                    app.drta_handles.p.setnxSD=0;
                    VarUpdate(app,Vname,Vupdate)
            end

        end

        % Value changed function: Choice_DropDown
        function Choice_DropDownValueChanged(app, event)
            DropChoice = app.Choice_DropDown.Value;

            switch DropChoice
                case 'Raw'
                    app.drta_handles.p.whichPlot = 1;
                case 'Wide 4-100'
                    app.drta_handles.p.whichPlot = 2;
                case 'High Theta 6-14'
                    app.drta_handles.p.whichPlot = 3;
                case 'Theta 2-14'
                    app.drta_handles.p.whichPlot = 4;
                case 'Beta 15-36'
                    app.drta_handles.p.whichPlot = 5;
                case 'Gamma1 35-65'
                    app.drta_handles.p.whichPlot = 6;
                case 'Gamma2 65-95'
                    app.drta_handles.p.whichPlot = 7;
                case 'Gamma 35-95'
                    app.drta_handles.p.whichPlot = 8;
                case 'Spikes 500-5000'
                    app.drta_handles.p.whichPlot = 9;
                case 'Spike var digital'
                    app.drta_handles.p.whichPlot = 10;
            end

            % Update handles structure
            app.drta_handles = drtaNWB_figureControl(app, app.drta_handles);
            %             drtaNWB_PlotBrowseTraces(app.drta_handles);

        end

        % Value changed function: type_DropDown
        function type_DropDownValueChanged(app, event)
            which_c_program = ...
                app.type_DropDown.Value;
            switch which_c_program
                case 'dropcnsampler'
                    app.drta_handles.p.which_c_program = 1;
                case 'dropcspm'
                    app.drta_handles.p.which_c_program = 2;
                case 'background'
                    app.drta_handles.p.which_c_program = 3;
                case 'spmult'
                    app.drta_handles.p.which_c_program = 4;
                case 'mspy'
                    app.drta_handles.p.which_c_program = 5;
                case 'osampler'
                    app.drta_handles.p.which_c_program = 6;
                case 'spm2mult'
                    app.drta_handles.p.which_c_program = 7;
                case 'lighton1'
                    app.drta_handles.p.which_c_program = 8;
                case 'lighton5'
                    app.drta_handles.p.which_c_program = 9;
                case 'dropcspm_conc'
                    app.drta_handles.p.which_c_program = 10;
                case 'Laser-triggered'
                    app.drta_handles.p.which_c_program = 11;
                case 'Laser-Merouann'
                    app.drta_handles.p.which_c_program = 12;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_c_program = 13;
                case 'Working_memory'
                    app.drta_handles.p.which_c_program = 14;
                case 'Continuous'
                    app.drta_handles.p.which_c_program = 15;
            end
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to drtaThrSaveMat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if (isfield(app.drta_handles.draq_d,'snip_samp'))
                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snip_samp');
                catch
                end

                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snip_index');
                catch
                end

                try
                    app.drta_handles = rmfield(app.drta_handles.draq_d,'snips');
                catch
                end
            end

            if (isfield(app.drta_handles.draq_d,'noEvents'))
                app.drta_handles = rmfield(app.drta_handles.draq_d,'noEvents');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'nEvPerType');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'nEventTypes');
                app.drta_handles = rmfield(app.drta_handles.draq_d,'eventlabels');

                try
                    app.drta_handles = rmfield(handles.draq_d,'events');
                    app.drta_handles = rmfield(handles.draq_d,'eventType');
                catch
                end


                try
                    app.drta_handles = rmfield(handles.draq_d,'blocks');
                catch
                end
            end
            % Adding meta data if entered
            if ~isempty(app.MSinput0.Value)
                Subject_MetaData = struct();
                Subject_MetaData.age = app.MSinput0.Value;
                Subject_MetaData.discription = app.MSinput1.Value;
                Subject_MetaData.genotype = app.MSinput2.Value;
                Subject_MetaData.sex = app.MSinput3.Value;
                Subject_MetaData.species = app.MSinput4.Value;
                Subject_MetaData.ID = app.MSinput5.Value;
                Subject_MetaData.weight = app.MSinput6.Value;
                Subject_MetaData.strain = app.MSinput7.Value;
                if app.MSinput8yes == 1
                    Subject_MetaData.DOB.year = app.MSinput9.Value;
                    Subject_MetaData.DOB.month = app.MSinput10.Value;
                    Subject_MetaData.day = app.MSinput11.Value;
                end
                app.drta_handles.p.SubjectMetaData = Subject_MetaData;
            end
            type_DropDownValueChanged(app)
            drtaGenerateEvents(app.drta_handles);
            app.SaveButton.Value = 0;
        end

        % Value changed function: Load_Button
        function Load_ButtonValueChanged(app, event)
            %{
            Loading the file that the user selected and opening the visual
            GUI for user interaction.
            %}
            app.FlagAlpha = true;
            protocolPick = app.protocolDropDown.Value;
            switch protocolPick
                case 'dropcspm'
                    app.drta_handles.p.which_protocol = 1;
                case 'laser (Ming)'
                    app.drta_handles.p.which_protocol = 2;
                case 'dropcnsampler'
                    app.drta_handles.p.which_protocol = 3;
                case 'laser (Merouann)'
                    app.drta_handles.p.which_protocol = 4;
                case 'dropc_conc'
                    app.drta_handles.p.which_protocol = 5;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_protocol = 6;
                case 'continuous'
                    app.drta_handles.p.which_protocol = 7;
                case 'working_memory'
                    app.drta_handles.p.which_protocol = 8;
            end
            app.drta_handles.trial_duration=app.trial_amt.Value;
            app.drta_handles.pre_dt=app.dt_amt.Value;
            app.dt_amt.Enable = false;
            app.trial_amt.Enable = false;
            app.type_DropDown.Enable = true;
            app.OutputText{1,1} = 'Loading ';

            app.ReadoutUpdate(protocolPick);
            if strcmp(app.drta_handles.p.FileName(end-2:end),'rhd')
                open_rhd_Callback(app);
            elseif strcmp(app.drta_handles.p.FileName(end-2:end),'.dg')
                drtaOpenDG_Callback(app);
            end
            CreatePlotWindow(app)
            

            %{
            Plotting the initial traces using default values
            %}
            app.drta_handles = drtaNWB_figureControl(app,app.drta_handles);
            app.Flags.fileLoaded = true;


        end

        % Value changed function: SelectFile_Button
        function SelectFile_ButtonValueChanged(app, event)
            %{
            Calling a user interface to select the file that needs to be
            opened. The output is the file name and path.
            %}
            [FileName,PathName] = uigetfile({'*.rhd';'*.dg'},'Select rhd or dg file to open');
            app.drta_handles.p.fullName=[PathName,FileName];
            app.drta_handles.p.FileName=FileName;
            app.drta_handles.p.PathName=PathName;
            app.file_name.Value = app.drta_handles.p.FileName;

        end

        % Selection changed function: NWBconvert
        function NWBconvertSelectionChanged(app, event)
            selectedButton = app.NWBconvert.SelectedObject;

            if selectedButton.Text == "Batch of Files"
                app.NextFile.Visible = "on";
                app.LastFile.Visible = "on";
                app.CurrentFileNumber.Visible = "on";
                app.FIlenumberProcessLabel.Visible = "on";
                app.NumberOfFileLabel.Visible = "on";
                app.FileNumber.Visible = "on";
                app.CreateFileforConversionButton.Visible = "on";
            else
                app.NextFile.Visible = "off";
                app.LastFile.Visible = "off";
                app.CurrentFileNumber.Visible = "off";
                app.FIlenumberProcessLabel.Visible = "off";
                app.NumberOfFileLabel.Visible = "off";
                app.FileNumber.Visible = "off";
                %                 app.OutputLocationLabel.Visible = "on";
                app.CreateFileforConversionButton.Visible = "off";
            end
        end

        % Value changed function: ConvertrhdFileButton
        function ConvertrhdFileButtonValueChanged(app, event)
            if app.SingleFileButton.Value == 1
                % this spot needs to have moving the files around
                C_location = pwd;
                cd("BlankSheet");
                copyfile("NWB_Settings.xlsx",C_location)
                cd(app.rhd.location)
                movefile(app.rhd.name,C_location)
                cd(C_location)
                WritingAllConvertFields(app)
                app.BatchFiles.amt = 1;

            else
                C_location = pwd;

            end
            for fm = 1:app.BatchFiles.amt
                if app.SingleFileButton.Value == 1
                    Name_File = py.numpy.str_(app.rhd.name);
                    Name_Output = py.numpy.str_(app.NWBFileName);
                    Name_dir = py.numpy.str_(pwd);
                else
                    cd(app.BatchFiles.table{fm,1}{:,:})
                    rhd_file = dir("*.rhd");
                    excel_file = dir("*.xlsx");
                    movefile(rhd_file.name,C_location);
                    movefile(excel_file.name,C_location);
                    cd(C_location)
                    filename = 'NWB_Settings.xlsx';
                    Input_file = readmatrix(filename,'Sheet',1,'Range','B3:B3','OutputType','string');
                    Output_file = readmatrix(filename,'Sheet',1,'Range','B4:B4','OutputType','string');
                    Name_File = py.numpy.str_(Input_file);
                    Name_Output = py.numpy.str_(Output_file);
                    Name_dir = py.numpy.str_(pwd);
                end

                cd(C_location)
                pyrunfile("drta3toNWB.py",Fname = Name_File,Outname = Name_Output,Fdir = Name_dir)
                if app.SingleFileButton.Value == 1
                    cd(app.FSinput11.Value)
                    CurrentDateTime = datetime("now");
                    Time_Full = char(CurrentDateTime);
                    Time_name = append(Time_Full(1:end-9),'_H-',Time_Full(end-7:end-6),'M-',Time_Full(end-4:end-3));
                    New_DIR = [app.FSinput1.Value(1:end-4),Time_name];
                    mkdir(New_DIR)
                    cd(New_DIR)
                    NewLocation = pwd;
                    cd(C_location)
                    movefile('NWB_Settings.xlsx' ,NewLocation)
                    movefile(app.FSinput0.Value,NewLocation)
                    movefile(app.FSinput1.Value,NewLocation)
                else
                    movefile('NWB_Settings.xlsx' ,app.BatchFiles.table{fm,1}{:,:})
                    movefile(Input_file,app.BatchFiles.table{fm,1}{:,:})
                    movefile(Output_file,app.BatchFiles.table{fm,1}{:,:})
                end
            end

        end

        % Button pushed function: ChooseOutputLocationButton
        function ChooseOutputLocationButtonPushed(app, event)
            [Fpath] = uigetdir();
            app.OutputLocation = Fpath;
            app.LocationForOutput.Value = Fpath;

        end

        % Button pushed function: NextFile
        function NewConverFilePlusButton(app, event)
            app.CreateFileforConversionButton.Enable = "on";
            app.ConvertrhdFileButton.Enable = "off";
            app.LocationForOutput.Enable = "off";
            app.InputFileName.Value = "Select a new file";
            app.OutputFileName.Value = "Select a new file";
            ClearAllConvertFileds(app)
            app.CreateFileforConversionButton.Value = 0;
            app.BatchFiles.amt = app.BatchFiles.amt + 1;
            app.CurrentFileNumber.Value = app.BatchFiles.amt;
            if app.BatchFiles.totCount > app.BatchFiles.amt
                C_location = pwd;
                cd(app.BatchFiles.table{app.BatchFiles.amt,1}{:,:})
                movefile("NWB_Setting.xlsx",C_location)
                cd(C_location)
                LoadAllConvertFileds(app)
                delete("NWB_Setting.xlsx")
            end

        end

        % Button pushed function: LastFile
        function LastFileRecallButton(app, event)

            tempCurrentLocation = pwd;
            tempFolderLocation = app.BatchFiles.table{app.BatchFiles.amt,1}{:,:};
            cd(tempFolderLocation);
            movefile("NWB_Settings.xlsx",tempCurrentLocation);
            cd(tempCurrentLocation);
            LoadAllConvertFileds(app)
            app.BatchFiles.amt = app.BatchFiles.amt - 1;
            app.CurrentFileNumber.Value = app.CurrentFileNumber.Value - 1;
            app.FileNumber.Value = app.BatchFiles.amt;
            if app.BatchFiles.amt == 0
                app.LastFile.Enable = "off";
                app.ConvertrhdFileButton.Enable = "off";
            end

        end

        % Value changed function: CreateFileforConversionButton
        function CreateConversionFileButton(app, event)
            %             app.CreateFileforConversionButton.Enable = "off";
            C_location = pwd;
            if app.BatchFiles.amt == 0
                app.BatchFiles.amt = app.BatchFiles.amt + 1;

                cd(app.FSinput11.Value)
                CurrentDateTime = datetime("now");
                CurrentDateTime = char(CurrentDateTime);
                NewBatchFile_Name = ['nwbBatchFile_',CurrentDateTime(1:11), ...
                    'H',CurrentDateTime(13:14), ...
                    'M',CurrentDateTime(16:17)];
                mkdir(NewBatchFile_Name);
                cd(NewBatchFile_Name);
                app.BatchFiles.location = pwd;
                cd(C_location)
                app.FSinput11.Enable = "off";
                app.LocationForOutput.Enable = "off";
            else
                %                 app.BatchFiles.amt = app.BatchFiles.amt - 1;
                CurrentDateTime = datetime("now");
            end
            cd(app.BatchFiles.location)
            Time_Full = char(CurrentDateTime);
            NewP_FolderName = [app.FSinput1.Value(1:end-4),'_',Time_Full(1:end-9), ...
                '_H-',Time_Full(end-7:end-6),'M-',Time_Full(end-4:end-3)];
            mkdir(NewP_FolderName)
            cd(NewP_FolderName)
            app.BatchFiles.table{app.BatchFiles.amt,1}{:,:} = pwd;
            cd(app.rhd.location)
            movefile(app.FSinput0.Value,app.BatchFiles.table{app.BatchFiles.amt,1}{:,:});
            cd(C_location);
            cd("BlankSheet")
            copyfile("NWB_Settings.xlsx",C_location)
            cd(C_location)
            WritingAllConvertFields(app)
            movefile("NWB_Settings.xlsx",app.BatchFiles.table{app.BatchFiles.amt,1}{:,:})
            app.FileNumber.Value = app.BatchFiles.amt;
            app.ConvertrhdFileButton.Enable = "on";
            if app.BatchFiles.totCount == app.BatchFiles.amt-1
                app.BatchFiles.totCount = app.BatchFiles.totCount +1;
            end
            app.NextFile.Visible = "on";
            %             app.CreateFileforConversionButton.Enable = "on";

        end

        % Value changed function: ChooseFileButton
        function ChooseFileButtonValueChanged(app, event)
            [FileName,PathName] = uigetfile({'*.rhd';'*.dg'},'Select rhd or dg file to open');
            app.rhd.location = PathName;
            app.rhd.name = FileName;
            app.FSinput0.Value = FileName;
            app.NWBFileName = [FileName(1:end-4),'.nwb'];
            app.FSinput1.Value = app.NWBFileName;
            app.InputFileName.Value = FileName;
            app.OutputFileName.Value = app.NWBFileName;
            app.ConvertrhdFileButton.Enable = "on";

        end

        % Value changed function: ChooseLocationButton
        function ChooseLocationButtonValueChanged(app, event)
            [Fpath] = uigetdir();
            app.FSinput11.Value = Fpath;
            app.LocationForOutput.Value = Fpath;
        end

        % Value changed function: Diff_CheckBox
        function Diff_CheckBoxValueChanged(app, event)
            if app.Diff_CheckBox.Value == 1
                handles.p.doSubtract=1;
            else
                handles.p.doSubtract=0;
            end
            VarUpdate(app,"doSubtract",handles.p.doSubtract)
        end

        % Button down function: SelectChannelsTab
        function SelectChannelsTabButtonDown(app, event)
            %{
            This function is used to choose the channels being shown in the
            figure shown in the browse traces tab
            Default is the first 16 channels
            %}


        end

        % Button down function: Browse_TracesTab
        function Browse_TracesTabButtonDown(app, event)
            if app.Flags.fileLoaded == true
                %{
                Plotting the traces after the tab has been selected
                %}
                CreatePlotWindow(app)
                app.drta_handles = drtaNWB_figureControl(app,app.drta_handles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.drta_loadTab = uitab(app.TabGroup);
            app.drta_loadTab.Title = 'drta_load';
            app.GridLayoutLoad = uigridlayout(app.drta_loadTab);
            app.SelectFile_Button = uibutton(app.GridLayoutLoad, 'state');
            app.SelectFile_Button.ValueChangedFcn = createCallbackFcn(app, @SelectFile_ButtonValueChanged, true);
            app.SelectFile_Button.Visible = 'off';
            app.SelectFile_Button.Text = 'Choose File (.rhd or .dg)';
            app.copyright_Label = uilabel(app.GridLayoutLoad);
            app.copyright_Label.Text = 'drta version 3.0 copyright Diego Restrepo';
            app.Load_Button = uibutton(app.GridLayoutLoad, 'state');
            app.Load_Button.ValueChangedFcn = createCallbackFcn(app, @Load_ButtonValueChanged, true);
            app.Load_Button.Visible = 'off';
            app.Load_Button.Text = 'Load File';
            app.ProgressReadout = uitextarea(app.GridLayoutLoad);
            app.protocolDropDown = uidropdown(app.GridLayoutLoad);
            app.protocolDropDown.Items = {'dropcspm', 'laser (Ming)', 'dropcnsampler', 'laser (Merouann)', 'dropc_conc', 'dropcspm_hf', 'continuous', 'working_memory'};
            app.protocolDropDown.Visible = 'off';
            app.protocolDropDown.Value = 'dropcspm';
            app.dt_Label = uilabel(app.GridLayoutLoad);
            app.dt_Label.Visible = 'off';
            app.dt_Label.Text = 'dt before odor end (s)';
            app.dt_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.dt_amt.Visible = 'off';
            app.dt_amt.Value = 6;
            app.Trial_Label = uilabel(app.GridLayoutLoad);
            app.Trial_Label.Visible = 'off';
            app.Trial_Label.Text = 'Trial duration (s)';
            app.trial_amt = uieditfield(app.GridLayoutLoad, 'numeric');
            app.trial_amt.Visible = 'off';
            app.trial_amt.Value = 9;
            app.file_name = uitextarea(app.GridLayoutLoad);
            app.file_name.Visible = 'off';
            app.file_name.Value = {'No File Selected'};
            app.AppType = uibuttongroup(app.GridLayoutLoad);
            app.AppType.SelectionChangedFcn = createCallbackFcn(app, @AppTypeSelectionChanged, true);
            app.rhdtoNWBButton = uiradiobutton(app.AppType);
            app.rhdtoNWBButton.Text = 'rhd to NWB';
            app.ViewandprocessrhdfileButton = uiradiobutton(app.AppType);
            app.ViewandprocessrhdfileButton.Text = 'View and process rhd file';
            app.ViewandprocessrhdfileButton.Value = true;
            app.drtaProcessVersion = uibuttongroup(app.GridLayoutLoad);
            app.drtaProcessVersion.SelectionChangedFcn = createCallbackFcn(app, @drtaProcessVersionSelectionChanged, true);
            app.drtaProcessVersion.Visible = 'off';
            app.drta20Button = uiradiobutton(app.drtaProcessVersion);
            app.drta20Button.Enable = 'off';
            app.drta20Button.Visible = 'off';
            app.drta20Button.Text = 'drta 2.0';
            app.drta30Button = uiradiobutton(app.drtaProcessVersion);
            app.drta30Button.Text = 'drta 3.0';
            app.drta30Button.Value = true;
            app.NWBconvert = uibuttongroup(app.GridLayoutLoad);
            app.NWBconvert.SelectionChangedFcn = createCallbackFcn(app, @NWBconvertSelectionChanged, true);
            app.NWBconvert.Visible = 'off';
            app.BatchofFilesButton = uiradiobutton(app.NWBconvert);
            app.BatchofFilesButton.Text = 'Batch of Files';
            app.SingleFileButton = uiradiobutton(app.NWBconvert);
            app.SingleFileButton.Text = 'Single File';
            app.SingleFileButton.Value = true;
            app.NumberOfFileLabel = uilabel(app.GridLayoutLoad);
            app.NumberOfFileLabel.Text = 'Number of files needing to be converted';
            app.FileNumber = uieditfield(app.GridLayoutLoad, 'numeric');
            app.FileNumber.Limits = [0 50];
            app.FileNumber.Visible = 'off';
            app.FIlenumberProcessLabel = uilabel(app.GridLayoutLoad);
            app.FIlenumberProcessLabel.Text = 'FIle number beign generated';
            app.LastFile = uibutton(app.GridLayoutLoad, 'push');
            app.LastFile.ButtonPushedFcn = createCallbackFcn(app, @LastFileRecallButton, true);
            app.LastFile.Visible = 'off';
            app.LastFile.Text = '-';
            app.NextFile = uibutton(app.GridLayoutLoad, 'push');
            app.NextFile.ButtonPushedFcn = createCallbackFcn(app, @NewConverFilePlusButton, true);
            app.NextFile.Visible = 'off';
            app.NextFile.Text = '+';
            app.CurrentFileNumber = uieditfield(app.GridLayoutLoad, 'numeric');
            app.CurrentFileNumber.Limits = [1 Inf];
            app.CurrentFileNumber.Visible = 'off';
            app.CurrentFileNumber.Value = 1;
            app.CreateFileforConversionButton = uibutton(app.GridLayoutLoad, 'state');
            app.CreateFileforConversionButton.ValueChangedFcn = createCallbackFcn(app, @CreateConversionFileButton, true);
            app.CreateFileforConversionButton.Visible = 'off';
            app.CreateFileforConversionButton.Text = 'Create File for Conversion';
            app.IntanfilenameLabel = uilabel(app.GridLayoutLoad);
            app.IntanfilenameLabel.Visible = 'off';
            app.IntanfilenameLabel.Text = 'Intan file name';
            app.NWBfilenameLabel = uilabel(app.GridLayoutLoad);
            app.NWBfilenameLabel.Visible = 'off';
            app.NWBfilenameLabel.Text = 'NWB file name';
            app.OutputFileName = uieditfield(app.GridLayoutLoad, 'text');
            app.OutputFileName.Visible = 'off';
            app.ConvertrhdFileButton = uibutton(app.GridLayoutLoad, 'state');
            app.ConvertrhdFileButton.ValueChangedFcn = createCallbackFcn(app, @ConvertrhdFileButtonValueChanged, true);
            app.ConvertrhdFileButton.Visible = 'off';
            app.ConvertrhdFileButton.Text = 'Convert rhd File';
            app.InputFileName = uitextarea(app.GridLayoutLoad);
            app.InputFileName.Visible = 'off';
            app.ChooseOutputLocationButton = uibutton(app.GridLayoutLoad, 'push');
            app.ChooseOutputLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOutputLocationButtonPushed, true);
            app.ChooseOutputLocationButton.Visible = 'off';
            app.ChooseOutputLocationButton.Text = 'Choose Output Location';
            app.LocationForOutput = uitextarea(app.GridLayoutLoad);
            app.LocationForOutput.Visible = 'off';
            app.OutputLocationLabel = uilabel(app.GridLayoutLoad);
            app.OutputLocationLabel.Visible = 'off';
            app.OutputLocationLabel.Text = 'Output Location';
            app.Browse_TracesTab = uitab(app.TabGroup);
            app.Browse_TracesTab.Title = 'Browse_Traces';
            app.Browse_TracesTab.ButtonDownFcn = createCallbackFcn(app, @Browse_TracesTabButtonDown, true);
            app.GridLayoutTraces = uigridlayout(app.Browse_TracesTab);
            app.uV_Label = uilabel(app.GridLayoutTraces);
            app.uV_Label.Text = 'uV';
            app.Yrange_Label = uilabel(app.GridLayoutTraces);
            app.Yrange_Label.Text = 'Y Range (uV):';
            app.Yrange_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Yrange_amt.ValueChangedFcn = createCallbackFcn(app, @Yrange_amtValueChanged, true);
            app.Yrange_amt.Value = 4000;
            app.Tnum_Larrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Larrow.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_Larrow.Tag = 'TnumMinus';
            app.Tnum_Larrow.Text = '<--';
            app.Tnum_Rarrow = uibutton(app.GridLayoutTraces, 'push');
            app.Tnum_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_Rarrow.Tag = 'TnumPlus';
            app.Tnum_Rarrow.Text = '-->';
            app.Tnum_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Tnum_amt.ValueChangedFcn = createCallbackFcn(app, @TrialNum_ButtonPushed, true);
            app.Tnum_amt.Tag = 'TnumField';
            app.Tnum_amt.Value = 1;
            app.Interval_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.Interval_amt.ValueChangedFcn = createCallbackFcn(app, @Interval_amtValueChanged, true);
            app.Interval_amt.Value = 1;
            app.Interval_Label = uilabel(app.GridLayoutTraces);
            app.Interval_Label.Text = 'Interval (sec):';
            app.Tnum_Label = uilabel(app.GridLayoutTraces);
            app.Tnum_Label.Text = 'Trial No:';
            app.Choice_DropDown = uidropdown(app.GridLayoutTraces);
            app.Choice_DropDown.Items = {'Raw', 'Wide 4-100', 'High Theta 6-14', 'Theta 2-14', 'Beta 15-36', 'Gamma1 35-65', 'Gamma2 65-95', 'Gamma 35-95', 'Spikes 500-5000', 'Spike var digital'};
            app.Choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @Choice_DropDownValueChanged, true);
            app.Choice_DropDown.Value = 'Raw';
            app.Yrange_DropDown = uidropdown(app.GridLayoutTraces);
            app.Yrange_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Digital'};
            app.Yrange_DropDown.ValueChangedFcn = createCallbackFcn(app, @Yrange_DropDownValueChanged, true);
            app.Yrange_DropDown.Value = 'all';
            app.Licks_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Licks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Licks_CheckBoxValueChanged, true);
            app.Licks_CheckBox.Text = 'Exclude Licks';
            app.MClust_Button = uibutton(app.GridLayoutTraces, 'state');
            app.MClust_Button.ValueChangedFcn = createCallbackFcn(app, @MClust_ButtonValueChanged, true);
            app.MClust_Button.Enable = 'off';
            app.MClust_Button.Visible = 'off';
            app.MClust_Button.Text = 'MClust';
            app.Diff_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.Diff_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Diff_CheckBoxValueChanged, true);
            app.Diff_CheckBox.Text = 'Differential';
            app.min_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.min_amt.ValueChangedFcn = createCallbackFcn(app, @ValueAmt_Changed, true);
            app.min_amt.Tag = 'LFPmin';
            app.min_amt.Value = 50;
            app.min_Label = uilabel(app.GridLayoutTraces);
            app.min_Label.Text = 'LFP min';
            app.max_Label = uilabel(app.GridLayoutTraces);
            app.max_Label.Text = 'LFP max';
            app.max_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.max_amt.Tag = 'LFPmax';
            app.max_amt.Value = 3900;
            app.nxSD_Label = uilabel(app.GridLayoutTraces);
            app.nxSD_Label.Text = 'Set nxSD';
            app.nxSD_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.nxSD_amt.Tag = 'nxSD';
            app.nxSD_amt.Value = 2.5;
            app.thr_amt = uieditfield(app.GridLayoutTraces, 'numeric');
            app.thr_amt.Tag = 'Thr';
            app.thr_Label = uilabel(app.GridLayoutTraces);
            app.thr_Label.Text = 'Set thr (uv)';
            app.type_DropDown = uidropdown(app.GridLayoutTraces);
            app.type_DropDown.Items = {'dropcspm', 'dropcnsampler', 'background', 'spmult', 'mspy', 'osampler', 'spm2mult', 'lighton1', 'lighton5', 'dropcspm_conc', 'Laser-triggered', 'Laser-Merouann', 'dropcspm_hf', 'Working_memory', 'Continuous'};
            app.type_DropDown.ValueChangedFcn = createCallbackFcn(app, @type_DropDownValueChanged, true);
            app.type_DropDown.Value = 'dropcspm';
            app.NWB_Label = uilabel(app.GridLayoutTraces);
            app.NWB_Label.Text = 'NWB format';
            app.SaveButton = uibutton(app.GridLayoutTraces, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.NWB_CheckBox = uicheckbox(app.GridLayoutTraces);
            app.NWB_CheckBox.Text = '';
            app.TrialOutcome = uilabel(app.GridLayoutTraces);
            app.TrialOutcome.Text = 'Trial';
            app.Plot_Panel = uipanel(app.GridLayoutTraces);
            app.GridLayout5 = uigridlayout(app.Plot_Panel);
            app.FileSettingsTab = uitab(app.TabGroup);
            app.FileSettingsTab.Title = 'File Settings';
            app.GridLayout = uigridlayout(app.FileSettingsTab);
            app.IntanfilenameLabel_2 = uilabel(app.GridLayout);
            app.IntanfilenameLabel_2.Text = 'Intan filename';
            app.NWBfilenameLabel_2 = uilabel(app.GridLayout);
            app.NWBfilenameLabel_2.Text = 'NWB filename';
            app.SessiondescriptionNote1Note2Label = uilabel(app.GridLayout);
            app.SessiondescriptionNote1Note2Label.Text = 'Session description [Note1 + Note2 ...]';
            app.Blocksperchunk1000Label = uilabel(app.GridLayout);
            app.Blocksperchunk1000Label.Text = 'Blocks per chunk [1000]';
            app.UseCompressionLabel = uilabel(app.GridLayout);
            app.UseCompressionLabel.Text = 'Use Compression';
            app.CompressionLevelLabel = uilabel(app.GridLayout);
            app.CompressionLevelLabel.Text = 'Compression Level';
            app.LowpassdescriptionLabel = uilabel(app.GridLayout);
            app.LowpassdescriptionLabel.Text = 'Lowpass description';
            app.HighpassdescriptionLabel = uilabel(app.GridLayout);
            app.HighpassdescriptionLabel.Text = 'Highpass description';
            app.MergefilesLabel = uilabel(app.GridLayout);
            app.MergefilesLabel.Text = 'Merge files';
            app.IncludetimesettingsLabel = uilabel(app.GridLayout);
            app.IncludetimesettingsLabel.Text = 'Include time settings';
            app.IncludesubjectmetadataLabel = uilabel(app.GridLayout);
            app.IncludesubjectmetadataLabel.Text = 'Include subject metadata';
            app.FSinput0 = uieditfield(app.GridLayout, 'text');
            app.FSinput1 = uieditfield(app.GridLayout, 'text');
            app.FSinput2 = uieditfield(app.GridLayout, 'text');
            app.FSinput3 = uieditfield(app.GridLayout, 'text');
            app.FSinput3.Value = '1000';
            app.FSinput5 = uieditfield(app.GridLayout, 'text');
            app.FSinput5.Value = '5';
            app.FSinput6 = uieditfield(app.GridLayout, 'text');
            app.FSinput7 = uieditfield(app.GridLayout, 'text');
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.FSinput4yes = uiradiobutton(app.ButtonGroup);
            app.FSinput4yes.Text = 'Yes';
            app.FSinput4yes.Value = true;
            app.FSinput4no = uiradiobutton(app.ButtonGroup);
            app.FSinput4no.Text = 'No';
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout);
            app.FSinput8yes = uiradiobutton(app.ButtonGroup_2);
            app.FSinput8yes.Text = 'Yes';
            app.FSinput8no = uiradiobutton(app.ButtonGroup_2);
            app.FSinput8no.Text = 'No';
            app.FSinput8no.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.GridLayout);
            app.FSinput9yes = uiradiobutton(app.ButtonGroup_3);
            app.FSinput9yes.Text = 'Yes';
            app.FSinput9no = uiradiobutton(app.ButtonGroup_3);
            app.FSinput9no.Text = 'No';
            app.FSinput9no.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.GridLayout);
            app.FSinput10yes = uiradiobutton(app.ButtonGroup_4);
            app.FSinput10yes.Text = 'Yes';
            app.FSinput10no = uiradiobutton(app.ButtonGroup_4);
            app.FSinput10no.Text = 'No';
            app.FSinput10no.Value = true;
            app.ChooseFileButton = uibutton(app.GridLayout, 'state');
            app.ChooseFileButton.ValueChangedFcn = createCallbackFcn(app, @ChooseFileButtonValueChanged, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.OutputlocationLabel = uilabel(app.GridLayout);
            app.OutputlocationLabel.Text = 'Output location';
            app.ChooseLocationButton = uibutton(app.GridLayout, 'state');
            app.ChooseLocationButton.ValueChangedFcn = createCallbackFcn(app, @ChooseLocationButtonValueChanged, true);
            app.ChooseLocationButton.Text = 'Choose Location';
            app.FSinput11 = uieditfield(app.GridLayout, 'text');
            app.SessionStartTimeSettingsTab = uitab(app.TabGroup);
            app.SessionStartTimeSettingsTab.Title = 'Session Start Time Settings';
            app.GridLayout2 = uigridlayout(app.SessionStartTimeSettingsTab);
            app.Manualsessionstartyear2019Label = uilabel(app.GridLayout2);
            app.Manualsessionstartyear2019Label.Text = 'Manual session start year [2019]';
            app.Manualsessionstartmonth1Label = uilabel(app.GridLayout2);
            app.Manualsessionstartmonth1Label.Text = 'Manual session start month [1]';
            app.manualsessionstartday17Label = uilabel(app.GridLayout2);
            app.manualsessionstartday17Label.Text = 'manual session start day [17]';
            app.Manualsessionstarthour16Label = uilabel(app.GridLayout2);
            app.Manualsessionstarthour16Label.Text = 'Manual session start hour [16]';
            app.Manualsessionstartminute23Label = uilabel(app.GridLayout2);
            app.Manualsessionstartminute23Label.Text = 'Manual session start minute [23]';
            app.Manualsessionstartsecond43Label = uilabel(app.GridLayout2);
            app.Manualsessionstartsecond43Label.Text = 'Manual session start second [43]';
            app.TSinput0 = uieditfield(app.GridLayout2, 'text');
            app.TSinput1 = uieditfield(app.GridLayout2, 'text');
            app.TSinput2 = uieditfield(app.GridLayout2, 'text');
            app.TSinput3 = uieditfield(app.GridLayout2, 'text');
            app.TSinput4 = uieditfield(app.GridLayout2, 'text');
            app.TSinput5 = uieditfield(app.GridLayout2, 'text');
            app.SubjectMetadataSettingsTab = uitab(app.TabGroup);
            app.SubjectMetadataSettingsTab.Title = 'Subject Metadata Settings';
            app.GridLayout3 = uigridlayout(app.SubjectMetadataSettingsTab);
            app.SubjectageLabel = uilabel(app.GridLayout3);
            app.SubjectageLabel.Text = 'Subject age';
            app.SubjectdescriptionLabel = uilabel(app.GridLayout3);
            app.SubjectdescriptionLabel.Text = 'Subject description';
            app.SubjectgenotypeLabel = uilabel(app.GridLayout3);
            app.SubjectgenotypeLabel.Text = 'Subject genotype';
            app.SubjectsexLabel = uilabel(app.GridLayout3);
            app.SubjectsexLabel.Text = 'Subject sex';
            app.SubjectspeciesLabel = uilabel(app.GridLayout3);
            app.SubjectspeciesLabel.Text = 'Subject species';
            app.SubjectIDLabel = uilabel(app.GridLayout3);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectweightkgLabel = uilabel(app.GridLayout3);
            app.SubjectweightkgLabel.Text = 'Subject weight (kg)';
            app.SubjectstrainLabel = uilabel(app.GridLayout3);
            app.SubjectstrainLabel.Text = 'Subject strain';
            app.IncludesubjectDOBLabel = uilabel(app.GridLayout3);
            app.IncludesubjectDOBLabel.Text = 'Include subject DOB';
            app.SubjectDOByear2009Label = uilabel(app.GridLayout3);
            app.SubjectDOByear2009Label.Text = 'Subject DOB year [2009]';
            app.SubjectDOBmonth9Label = uilabel(app.GridLayout3);
            app.SubjectDOBmonth9Label.Text = 'Subject DOB month [9]';
            app.SubjectDOBday21Label = uilabel(app.GridLayout3);
            app.SubjectDOBday21Label.Text = 'Subject DOB day [21]';
            app.MSinput0 = uieditfield(app.GridLayout3, 'text');
            app.MSinput1 = uieditfield(app.GridLayout3, 'text');
            app.MSinput2 = uieditfield(app.GridLayout3, 'text');
            app.MSinput3 = uieditfield(app.GridLayout3, 'text');
            app.MSinput4 = uieditfield(app.GridLayout3, 'text');
            app.MSinput5 = uieditfield(app.GridLayout3, 'text');
            app.MSinput6 = uieditfield(app.GridLayout3, 'text');
            app.MSinput7 = uieditfield(app.GridLayout3, 'text');
            app.ButtonGroup_5 = uibuttongroup(app.GridLayout3);
            app.MSinput8yes = uiradiobutton(app.ButtonGroup_5);
            app.MSinput8yes.Text = 'Yes';
            app.MSinput8yes.Value = true;
            app.MSinput8no = uiradiobutton(app.ButtonGroup_5);
            app.MSinput8no.Text = 'No';
            app.MSinput9 = uieditfield(app.GridLayout3, 'text');
            app.MSinput10 = uieditfield(app.GridLayout3, 'text');
            app.MSinput11 = uieditfield(app.GridLayout3, 'text');
            app.SelectChannelsTab = uitab(app.TabGroup);
            app.SelectChannelsTab.Title = 'Select Channels';
            app.SelectChannelsTab.ButtonDownFcn = createCallbackFcn(app, @SelectChannelsTabButtonDown, true);
            app.ChannelPanel = uipanel(app.SelectChannelsTab);
            app.GridLayout4 = uigridlayout(app.ChannelPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drtaNWB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end